classdef ppt_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        current_plot                 matlab.ui.control.UIAxes
        LcEditFieldLabel             matlab.ui.control.Label
        Lc                           matlab.ui.control.NumericEditField
        LpeEditFieldLabel            matlab.ui.control.Label
        Lpe                          matlab.ui.control.NumericEditField
        LeEditFieldLabel             matlab.ui.control.Label
        Le                           matlab.ui.control.NumericEditField
        RcEditFieldLabel             matlab.ui.control.Label
        Rc                           matlab.ui.control.NumericEditField
        ReEditFieldLabel             matlab.ui.control.Label
        Re                           matlab.ui.control.NumericEditField
        RpeEditFieldLabel            matlab.ui.control.Label
        Rpe                          matlab.ui.control.NumericEditField
        RpEditFieldLabel             matlab.ui.control.Label
        Rp                           matlab.ui.control.NumericEditField
        CEditFieldLabel              matlab.ui.control.Label
        C                            matlab.ui.control.NumericEditField
        SolveButton                  matlab.ui.control.StateButton
        V0EditFieldLabel             matlab.ui.control.Label
        V0                           matlab.ui.control.NumericEditField
        hEditFieldLabel              matlab.ui.control.Label
        h                            matlab.ui.control.NumericEditField
        wEditFieldLabel              matlab.ui.control.Label
        w                            matlab.ui.control.NumericEditField
        lEditFieldLabel              matlab.ui.control.Label
        l                            matlab.ui.control.NumericEditField
        TeEditFieldLabel             matlab.ui.control.Label
        Te                           matlab.ui.control.NumericEditField
        n_eEditFieldLabel            matlab.ui.control.Label
        n_e                          matlab.ui.control.NumericEditField
        t_pulseEditFieldLabel        matlab.ui.control.Label
        t_pulse                      matlab.ui.control.NumericEditField
        deltaEditFieldLabel          matlab.ui.control.Label
        delta                        matlab.ui.control.NumericEditField
        alphaEditFieldLabel          matlab.ui.control.Label
        alpha                        matlab.ui.control.NumericEditField
        m_bitEditFieldLabel          matlab.ui.control.Label
        m_bit                        matlab.ui.control.NumericEditField
        muEditFieldLabel             matlab.ui.control.Label
        mu                           matlab.ui.control.NumericEditField
        MaxStepEditFieldLabel        matlab.ui.control.Label
        MaxStep                      matlab.ui.control.NumericEditField
        t_finalEditFieldLabel        matlab.ui.control.Label
        t_final                      matlab.ui.control.NumericEditField
        energy_plot                  matlab.ui.control.UIAxes
        ThrusterGeometryButtonGroup  matlab.ui.container.ButtonGroup
        ParallelPlateButton          matlab.ui.control.RadioButton
        CoaxialButton                matlab.ui.control.RadioButton
    end

    methods (Access = private)

        % Value changed function: SolveButton
        function SolveButtonValueChanged(app, event)
            tspan = [0 app.t_final.Value];
            y0 = [0,0,0,0];
            opts = odeset('RelTol',1e-6,'AbsTol',1e-8,'MaxStep', app.MaxStep.Value);
            Req = app.Rc.Value + app.Re.Value + app.Rpe.Value + app.Rp.Value;
            
            [t,y] = ode45(@(t,y) ppt(t,y,app.V0.Value,app.C.Value,Req,app.mu.Value,app.h.Value,app.w.Value,app.m_bit.Value,app.Lc.Value,app.Le.Value,app.delta.Value), tspan, y0, opts);
            
            I = y(:,4);
            V = app.V0.Value - y(:,2)/app.C.Value;
            Ec = app.C.Value*V.^2/2;
            Eohm = zeros(length(t),1);
            for i = 2:length(t)
                int = trapz(t(1:i),Req*y(1:i,4).*y(1:i,4));
                Eohm(i) = int;
            end
            
            %plot 1
            yyaxis(app.current_plot,'left')
            plot(app.current_plot,t,V)
            yyaxis(app.current_plot,'right')
            plot(app.current_plot,t,I)
            ylabel(app.current_plot,'Current')
            xlim(app.current_plot,[0,app.t_final.Value])
            
            %plot 2
            plot(app.energy_plot,t,Eohm)
            hold(app.energy_plot,'on')
            plot(app.energy_plot,t,Ec)
            legend(app.energy_plot,'Ohmic','Capacitor')
            
            function y = ppt(t,y0,V0,C,Req,mu,h,w,m_bit,Lc,Le,delta)
                Lpe = mu*h*y0(1)/w +mu*delta*h/(2*w);
                L = Lpe + Lc + Le;
    
                y = zeros(4,1);
                y(1) = y0(3);
                y(2) = y0(4);
                y(3) = Lpe*y0(4)*y0(4)/(2*m_bit);
                y(4) = (-y0(2)/C - mu*h*y0(3)*y0(4)/w - Req*y0(4) + V0)/(L);

            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.current_plot = uiaxes(app.UIFigure);
            title(app.current_plot, 'I(t)')
            xlabel(app.current_plot, 'Time')
            ylabel(app.current_plot, 'Voltage')
            app.LcEditFieldLabel = uilabel(app.UIFigure);
            app.LcEditFieldLabel.Text = 'Lc';
            app.Lc = uieditfield(app.UIFigure, 'numeric');
            app.LpeEditFieldLabel = uilabel(app.UIFigure);
            app.LpeEditFieldLabel.Text = 'Lpe';
            app.Lpe = uieditfield(app.UIFigure, 'numeric');
            app.LeEditFieldLabel = uilabel(app.UIFigure);
            app.LeEditFieldLabel.Text = 'Le';
            app.Le = uieditfield(app.UIFigure, 'numeric');
            app.Le.Value = 3.4e-08;
            app.RcEditFieldLabel = uilabel(app.UIFigure);
            app.RcEditFieldLabel.Text = 'Rc';
            app.Rc = uieditfield(app.UIFigure, 'numeric');
            app.Rc.Value = 0.03;
            app.ReEditFieldLabel = uilabel(app.UIFigure);
            app.ReEditFieldLabel.Text = 'Re';
            app.Re = uieditfield(app.UIFigure, 'numeric');
            app.RpeEditFieldLabel = uilabel(app.UIFigure);
            app.RpeEditFieldLabel.Text = 'Rpe';
            app.Rpe = uieditfield(app.UIFigure, 'numeric');
            app.RpEditFieldLabel = uilabel(app.UIFigure);
            app.RpEditFieldLabel.Text = 'Rp';
            app.Rp = uieditfield(app.UIFigure, 'numeric');
            app.Rp.Value = 0.074;
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.C = uieditfield(app.UIFigure, 'numeric');
            app.C.Value = 2e-06;
            app.SolveButton = uibutton(app.UIFigure, 'state');
            app.SolveButton.ValueChangedFcn = createCallbackFcn(app, @SolveButtonValueChanged, true);
            app.SolveButton.Text = 'Solve';
            app.V0EditFieldLabel = uilabel(app.UIFigure);
            app.V0EditFieldLabel.Text = 'V0';
            app.V0 = uieditfield(app.UIFigure, 'numeric');
            app.V0.Value = 1360;
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.h = uieditfield(app.UIFigure, 'numeric');
            app.wEditFieldLabel = uilabel(app.UIFigure);
            app.wEditFieldLabel.Text = 'w';
            app.w = uieditfield(app.UIFigure, 'numeric');
            app.w.Value = 0.074;
            app.lEditFieldLabel = uilabel(app.UIFigure);
            app.lEditFieldLabel.Text = 'l';
            app.l = uieditfield(app.UIFigure, 'numeric');
            app.l.Value = 2e-06;
            app.TeEditFieldLabel = uilabel(app.UIFigure);
            app.TeEditFieldLabel.Text = 'Te';
            app.Te = uieditfield(app.UIFigure, 'numeric');
            app.Te.Value = 1.5;
            app.n_eEditFieldLabel = uilabel(app.UIFigure);
            app.n_eEditFieldLabel.Text = 'n_e';
            app.n_e = uieditfield(app.UIFigure, 'numeric');
            app.n_e.Value = 1e+21;
            app.t_pulseEditFieldLabel = uilabel(app.UIFigure);
            app.t_pulseEditFieldLabel.Text = 't_pulse';
            app.t_pulse = uieditfield(app.UIFigure, 'numeric');
            app.t_pulse.Value = 4e-07;
            app.deltaEditFieldLabel = uilabel(app.UIFigure);
            app.deltaEditFieldLabel.Text = 'delta';
            app.delta = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha = uieditfield(app.UIFigure, 'numeric');
            app.alpha.Value = 1;
            app.m_bitEditFieldLabel = uilabel(app.UIFigure);
            app.m_bitEditFieldLabel.Text = 'm_bit';
            app.m_bit = uieditfield(app.UIFigure, 'numeric');
            app.m_bit.Value = 1e-05;
            app.muEditFieldLabel = uilabel(app.UIFigure);
            app.muEditFieldLabel.Text = 'mu';
            app.mu = uieditfield(app.UIFigure, 'numeric');
            app.mu.Value = 1.2566e-06;
            app.MaxStepEditFieldLabel = uilabel(app.UIFigure);
            app.MaxStepEditFieldLabel.Text = 'MaxStep';
            app.MaxStep = uieditfield(app.UIFigure, 'numeric');
            app.MaxStep.Value = 1e-08;
            app.t_finalEditFieldLabel = uilabel(app.UIFigure);
            app.t_finalEditFieldLabel.Text = 't_final';
            app.t_final = uieditfield(app.UIFigure, 'numeric');
            app.t_final.Value = 2.5e-06;
            app.energy_plot = uiaxes(app.UIFigure);
            title(app.energy_plot, '')
            xlabel(app.energy_plot, 'Time')
            ylabel(app.energy_plot, 'Energy')
            app.ThrusterGeometryButtonGroup = uibuttongroup(app.UIFigure);
            app.ThrusterGeometryButtonGroup.Title = 'Thruster Geometry';
            app.ParallelPlateButton = uiradiobutton(app.ThrusterGeometryButtonGroup);
            app.ParallelPlateButton.Text = 'Parallel Plate';
            app.ParallelPlateButton.Value = true;
            app.CoaxialButton = uiradiobutton(app.ThrusterGeometryButtonGroup);
            app.CoaxialButton.Text = 'Coaxial';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ppt_design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end